classdef OS_Cali_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        XSpinnerLabel  matlab.ui.control.Label
        XSpinner       matlab.ui.control.Spinner
        YSpinnerLabel  matlab.ui.control.Label
        YSpinner       matlab.ui.control.Spinner
        OKButton       matlab.ui.control.Button
        UIAxes         matlab.ui.control.UIAxes
    end

%%% App created by Nawara T. (Mattheyses lab - 05/20/2022) compatible
%%% with MATLAB R2020b - App opens the grid pictures and allws for their
%%% aligment
    
%This work is licensed under the Creative Commons Attribution 4.0
%International License. To view a copy of this license, visit
%http://creativecommons.org/licenses/by/4.0/ or send a letter to Creative
%Commons, PO Box 1866, Mountain View, CA 94042, USA.

    properties (Access = public)
        Property % Description
        X
        Y
        AA
        BB
        BB_shifted_X
        BB_shifted_X_Y
        Colormaps
    end
    
    methods (Access = private)
        
        function plotData(app)
            cla(app.UIAxes,'reset');
            Composit_X = CompositeRGB(app.AA, app.Colormaps.Cyan, [0 0.7], app.BB_shifted_X, app.Colormaps.Magenta, [0 0.7]);
            f1 = imshow(Composit_X,'Parent',app.UIAxes);
        end
        
        function plotData_Y(app)
            cla(app.UIAxes,'reset');
            Composit_Y = CompositeRGB(app.AA, app.Colormaps.Cyan, [0 0.7], app.BB_shifted_X_Y, app.Colormaps.Magenta, [0 0.7]);
            f1 = imshow(Composit_Y,'Parent',app.UIAxes);
        end
        
        function setDefaults(app)
            app.XSpinner.Value = 0;
            app.YSpinner.Value = 0;
            app.BB_shifted_X = app.BB;
            app.plotData();
            
            
         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.AA = evalin('base','Grid_488_raw');
            app.BB = evalin('base','Grid_647_raw');
            app.Colormaps = evalin('base','Colormaps');
            app.setDefaults()       
        end

        % Value changing function: XSpinner
        function XSpinnerValueChanging(app, event)
            app.X = event.Value;
            app.BB_shifted_X = imtranslate(app.BB,[app.X, 0]);
            app.plotData(); 
        end

        % Value changing function: YSpinner
        function YSpinnerValueChanging(app, event)
            app.Y = event.Value;
            app.BB_shifted_X_Y = imtranslate(app.BB_shifted_X,[0, app.Y]);
            app.plotData_Y(); 
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
        assignin('base','X', app.X)
        assignin('base','Y',app.Y)
        app.delete
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XSpinnerLabel = uilabel(app.UIFigure);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.UIFigure);
            app.XSpinner.ValueChangingFcn = createCallbackFcn(app, @XSpinnerValueChanging, true);
            app.YSpinnerLabel = uilabel(app.UIFigure);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.UIFigure);
            app.YSpinner.ValueChangingFcn = createCallbackFcn(app, @YSpinnerValueChanging, true);
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Align images')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OS_Cali_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end